classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CommandTextAreaLabel        matlab.ui.control.Label
        CommandTextArea             matlab.ui.control.TextArea
        SelectButton                matlab.ui.control.Button
        MenuDropDownLabel           matlab.ui.control.Label
        MenuDropDown                matlab.ui.control.DropDown
        DegreeSliderLabel           matlab.ui.control.Label
        DegreeSlider                matlab.ui.control.Slider
        LinkDropDownLabel           matlab.ui.control.Label
        LinkDropDown                matlab.ui.control.DropDown
        CollisiondetectionTextAreaLabel  matlab.ui.control.Label
        CollisiondetectionTextArea  matlab.ui.control.TextArea
        EmergencyButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        Menu; 
        Link;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            if app.Menu == 2
                text1 = sprintf('Second menu\n');
                % put funtion for second menu
            elseif app.Menu == 3
                text1 = sprintf('Third menu\n');
                % put funtion for third menu
            elseif app.Menu == 4
                text1 = sprintf('Forth menu\n');
                % put funtion for forth menu
            else
                text1 = sprintf('First menu\n');
                % put funtion for first menu
            end
            app.CommandTextArea.Value = text1;
        end

        % Drop down opening function: MenuDropDown
        function MenuDropDownOpening(app, event)
            
        end

        % Value changed function: MenuDropDown
        function MenuDropDownValueChanged(app, event)
            switch app.MenuDropDown.Value
                case 'Boba 1'
                    app.Menu=1;
                case 'Boba 2'
                    app.Menu=2;
                case 'Boba 3'
                    app.Menu=3;
                case 'Boba 4'
                    app.Menu=4;
            end
        end

        % Value changed function: CommandTextArea
        function CommandTextAreaValueChanged(app, event)
            value = app.CommandTextArea.Value;
        end

        % Value changed function: LinkDropDown
        function LinkDropDownValueChanged(app, event)
            switch app.LinkDropDown.Value
                case '1'
                    app.Link=1;
                    % link 1 code
                case '2'
                    app.Link=2;
                    % link 2 code
                case '3'
                    app.Link=3;
                    % link 3 code
                case '4'
                    app.Link=4;
                    % link 4 code
                case '5'
                    app.Link=5;
                    % link 5 code
                case '6'
                    app.Link=6;
                    % link 6 code
                case '7'
                    app.Link=7;
                    % link 7 code
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CommandTextAreaLabel = uilabel(app.UIFigure);
            app.CommandTextAreaLabel.Text = 'Command';
            app.CommandTextArea = uitextarea(app.UIFigure);
            app.CommandTextArea.ValueChangedFcn = createCallbackFcn(app, @CommandTextAreaValueChanged, true);
            app.CommandTextArea.Value = {'Please select the menu'};
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.MenuDropDownLabel = uilabel(app.UIFigure);
            app.MenuDropDownLabel.Text = 'Menu';
            app.MenuDropDown = uidropdown(app.UIFigure);
            app.MenuDropDown.Items = {'Boba 1', 'Boba 2', 'Boba 3', 'Boba 4'};
            app.MenuDropDown.DropDownOpeningFcn = createCallbackFcn(app, @MenuDropDownOpening, true);
            app.MenuDropDown.ValueChangedFcn = createCallbackFcn(app, @MenuDropDownValueChanged, true);
            app.MenuDropDown.Value = 'Boba 1';
            app.DegreeSliderLabel = uilabel(app.UIFigure);
            app.DegreeSliderLabel.Text = 'Degree';
            app.DegreeSlider = uislider(app.UIFigure);
            app.LinkDropDownLabel = uilabel(app.UIFigure);
            app.LinkDropDownLabel.Text = 'Link';
            app.LinkDropDown = uidropdown(app.UIFigure);
            app.LinkDropDown.Items = {'1', '2', '3', '4', '5', '6', '7'};
            app.LinkDropDown.ValueChangedFcn = createCallbackFcn(app, @LinkDropDownValueChanged, true);
            app.LinkDropDown.Value = '1';
            app.CollisiondetectionTextAreaLabel = uilabel(app.UIFigure);
            app.CollisiondetectionTextAreaLabel.Text = 'Collision detection';
            app.CollisiondetectionTextArea = uitextarea(app.UIFigure);
            app.EmergencyButton = uibutton(app.UIFigure, 'push');
            app.EmergencyButton.Text = 'Emergency Button';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end