classdef GUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        MenuTab                  matlab.ui.container.Tab
        PremiumPearlButton       matlab.ui.control.Button
        MatchaButton             matlab.ui.control.Button
        SpecialButton            matlab.ui.control.Button
        CommandEditFieldLabel    matlab.ui.control.Label
        CommandEditField         matlab.ui.control.EditField
        ProgressEditFieldLabel   matlab.ui.control.Label
        ProgressEditField        matlab.ui.control.EditField
        EStopButton              matlab.ui.control.Button
        PauseButton              matlab.ui.control.Button
        CollisionEditFieldLabel  matlab.ui.control.Label
        CollisionEditField       matlab.ui.control.EditField
        Tab2                     matlab.ui.container.Tab
        q1SpinnerLabel           matlab.ui.control.Label
        q1Spinner                matlab.ui.control.Spinner
        q2SpinnerLabel           matlab.ui.control.Label
        q2Spinner                matlab.ui.control.Spinner
        q3SpinnerLabel           matlab.ui.control.Label
        q3Spinner                matlab.ui.control.Spinner
        q4SpinnerLabel           matlab.ui.control.Label
        q4Spinner                matlab.ui.control.Spinner
        q5SpinnerLabel           matlab.ui.control.Label
        q5Spinner                matlab.ui.control.Spinner
        q6SpinnerLabel           matlab.ui.control.Label
        q6Spinner                matlab.ui.control.Spinner
        q7SpinnerLabel           matlab.ui.control.Label
        q7Spinner                matlab.ui.control.Spinner
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PremiumPearlButton
        function PremiumPearlButtonPushed(app, event)
            app.CommandEditField.Value = sprintf('Premium Pearl is selected\n');
            app.ProgressEditField.Value = sprintf('Order is in Progress');
        end

        % Value changing function: CommandEditField
        function CommandEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: ProgressEditField
        function ProgressEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: MatchaButton
        function MatchaButtonPushed(app, event)
            app.CommandEditField.Value = sprintf('Matcha is selected\n');
            app.ProgressEditField.Value = sprintf('Order is in Progress');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MenuTab = uitab(app.TabGroup);
            app.MenuTab.Title = 'Menu';
            app.PremiumPearlButton = uibutton(app.MenuTab, 'push');
            app.PremiumPearlButton.ButtonPushedFcn = createCallbackFcn(app, @PremiumPearlButtonPushed, true);
            app.PremiumPearlButton.Text = 'Premium Pearl';
            app.MatchaButton = uibutton(app.MenuTab, 'push');
            app.MatchaButton.ButtonPushedFcn = createCallbackFcn(app, @MatchaButtonPushed, true);
            app.MatchaButton.Text = 'Matcha';
            app.SpecialButton = uibutton(app.MenuTab, 'push');
            app.SpecialButton.Text = 'Special';
            app.CommandEditFieldLabel = uilabel(app.MenuTab);
            app.CommandEditFieldLabel.Text = 'Command';
            app.CommandEditField = uieditfield(app.MenuTab, 'text');
            app.CommandEditField.ValueChangingFcn = createCallbackFcn(app, @CommandEditFieldValueChanging, true);
            app.ProgressEditFieldLabel = uilabel(app.MenuTab);
            app.ProgressEditFieldLabel.Text = 'Progress';
            app.ProgressEditField = uieditfield(app.MenuTab, 'text');
            app.ProgressEditField.ValueChangingFcn = createCallbackFcn(app, @ProgressEditFieldValueChanging, true);
            app.EStopButton = uibutton(app.MenuTab, 'push');
            app.EStopButton.Text = 'E-Stop';
            app.PauseButton = uibutton(app.MenuTab, 'push');
            app.PauseButton.Text = 'Pause';
            app.CollisionEditFieldLabel = uilabel(app.MenuTab);
            app.CollisionEditFieldLabel.Text = 'Collision';
            app.CollisionEditField = uieditfield(app.MenuTab, 'text');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.q1SpinnerLabel = uilabel(app.Tab2);
            app.q1SpinnerLabel.Text = 'q1';
            app.q1Spinner = uispinner(app.Tab2);
            app.q2SpinnerLabel = uilabel(app.Tab2);
            app.q2SpinnerLabel.Text = 'q2';
            app.q2Spinner = uispinner(app.Tab2);
            app.q3SpinnerLabel = uilabel(app.Tab2);
            app.q3SpinnerLabel.Text = 'q3';
            app.q3Spinner = uispinner(app.Tab2);
            app.q4SpinnerLabel = uilabel(app.Tab2);
            app.q4SpinnerLabel.Text = 'q4';
            app.q4Spinner = uispinner(app.Tab2);
            app.q5SpinnerLabel = uilabel(app.Tab2);
            app.q5SpinnerLabel.Text = 'q5';
            app.q5Spinner = uispinner(app.Tab2);
            app.q6SpinnerLabel = uilabel(app.Tab2);
            app.q6SpinnerLabel.Text = 'q6';
            app.q6Spinner = uispinner(app.Tab2);
            app.q7SpinnerLabel = uilabel(app.Tab2);
            app.q7SpinnerLabel.Text = 'q7';
            app.q7Spinner = uispinner(app.Tab2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end